classdef PolarPlotAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ExponentLabel    matlab.ui.control.Label
        BaseLabel        matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        bEditField       matlab.ui.control.NumericEditField
        bEditFieldLabel  matlab.ui.control.Label
        aEditField       matlab.ui.control.NumericEditField
        aEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Pax % Polar axes
    end
    
    methods (Access = private)
        
        function updateplot(app)
            
            % Define theta. Get a and b
            theta = 0:pi/1800:2*pi;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            
            % Calculate r and plot it
            r = (1 + sin(a.*theta)).^(1/b);
            plotline = polarplot(app.Pax, theta, r);
            
            % Set line color
            plotline.Color = app.DropDown.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Replace 0 in equation with unicode theta
            app.BaseLabel.Text = ['r = ( 1 + sin(a' char(00952) ') )'];
            
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [260 55 230 230];
            
            % Plot the polar function
            updateplot(app);
        end

        % Value changed function: DropDown, aEditField, bEditField
        function ComponentValueChanged(app, event)
            % Plot the polar function
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.aEditField.Value = 5;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Limits = [2.22044604925031e-16 Inf];
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.bEditField.Value = 2;
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Blue Line', 'Red Line', 'Green Line', 'Black Line'};
            app.DropDown.ItemsData = {'b', 'r', 'g', 'k'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.DropDown.Value = 'b';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'r = ( 1 + sin(a0) )';
            app.ExponentLabel = uilabel(app.UIFigure);
            app.ExponentLabel.Text = '1/b';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolarPlotAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end